classdef GuiLogger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartreadingButton              matlab.ui.control.Button
        SavecontinuousCheckBox          matlab.ui.control.CheckBox
        SavelogButton                   matlab.ui.control.Button
        ClearlogButton                  matlab.ui.control.Button
        Plot                            matlab.ui.control.UIAxes
        LogfileEditFieldLabel           matlab.ui.control.Label
        LogfileEditField                matlab.ui.control.EditField
        ReadingintervalsEditFieldLabel  matlab.ui.control.Label
        ReadingintervalsEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        name = 'Logger' % Name of the logger window
        Lg % Logger object
    end

    properties (SetAccess=protected, GetAccess=public)
        linked_elem_list={}; % List of elements updated by updateGui
    end

    methods (Access = private)
    
        function createControlLinks(app)
            linkControlElement(app, ...
                app.ChannelDropDown, 'channel');
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            p=inputParser();
            filt_varargin = parseClassInputs(p, varargin{:});
            
            if ismember('Lg',p.UsingDefaults)
                % Create a logger, if it was not supplied externally
                app.Lg = MyLogger(filt_varargin{:});
            end
            
            createControlLinks(app);
            updateGui(app, app.Lg);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartreadingButton = uibutton(app.UIFigure, 'push');
            app.StartreadingButton.Text = 'Start reading';
            app.SavecontinuousCheckBox = uicheckbox(app.UIFigure);
            app.SavecontinuousCheckBox.Text = 'Save continuous';
            app.SavelogButton = uibutton(app.UIFigure, 'push');
            app.SavelogButton.Text = 'Save log';
            app.ClearlogButton = uibutton(app.UIFigure, 'push');
            app.ClearlogButton.Text = 'Clear log';
            app.Plot = uiaxes(app.UIFigure);
            title(app.Plot, 'Title')
            xlabel(app.Plot, 'X')
            ylabel(app.Plot, 'Y')
            app.LogfileEditFieldLabel = uilabel(app.UIFigure);
            app.LogfileEditFieldLabel.Text = 'Log file';
            app.LogfileEditField = uieditfield(app.UIFigure, 'text');
            app.LogfileEditField.Value = 'M:\Measurement Campaigns\2018-07-13 Pressure Log';
            app.ReadingintervalsEditFieldLabel = uilabel(app.UIFigure);
            app.ReadingintervalsEditFieldLabel.Text = 'Reading interval, s';
            app.ReadingintervalsEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiLogger(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end